classdef app2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        Panel               matlab.ui.container.Panel
        EqualPanel          matlab.ui.control.EditField
        NumberofRollsPanel  matlab.ui.container.Panel
        Number              matlab.ui.control.EditField
        TotalPanel          matlab.ui.container.Panel
        RollTotalTextArea   matlab.ui.control.EditField
        Die1Panel           matlab.ui.container.Panel
        Roll1TextArea       matlab.ui.control.EditField
        Die2Panel           matlab.ui.container.Panel
        Roll2TextArea       matlab.ui.control.EditField
        ROLLButton          matlab.ui.control.Button
        GunLakeCasinoLabel  matlab.ui.control.Label
        UIAxes              matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        numRolls = 0; % Description
        dieTotalHistory = zeros(11,1) % Description
        possibleDieTotal = [2:12] % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)


        end

        % Button pushed function: ROLLButton
        function ROLLButtonPushed(app, event)
            die1 = randi([1 6]);
            die2 = randi([1 6]);
            rollTotal = die1 + die2;
            app.numRolls = app.numRolls + 1;
            
            app.Roll1TextArea.Value = num2str(die1);
            app.Roll2TextArea.Value = num2str(die2);
            app.RollTotalTextArea.Value = num2str(rollTotal);
            app.Number.Value = num2str(app.numRolls);
            
            app.dieTotalHistory(rollTotal-1) = app.dieTotalHistory(rollTotal-1) + 1;
            
            bar(app.UIAxes, app.possibleDieTotal, app.dieTotalHistory);

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Roll Results ')
            xlabel(app.UIAxes, 'Roll Total')
            ylabel(app.UIAxes, 'Frequency')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.GunLakeCasinoLabel = uilabel(app.UIFigure);
            app.GunLakeCasinoLabel.Text = 'Gun Lake Casino';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Panel';
            app.ROLLButton = uibutton(app.Panel, 'push');
            app.ROLLButton.ButtonPushedFcn = createCallbackFcn(app, @ROLLButtonPushed, true);
            app.ROLLButton.Text = 'ROLL ';
            app.Die2Panel = uipanel(app.Panel);
            app.Die2Panel.Title = 'Die 1';
            app.Roll2TextArea = uieditfield(app.Die2Panel, 'text');
            app.Roll2TextArea.Value = '#';
            app.Die1Panel = uipanel(app.Panel);
            app.Die1Panel.Title = 'Die 2';
            app.Roll1TextArea = uieditfield(app.Die1Panel, 'text');
            app.Roll1TextArea.Value = '#';
            app.TotalPanel = uipanel(app.Panel);
            app.TotalPanel.Title = 'Total ';
            app.RollTotalTextArea = uieditfield(app.TotalPanel, 'text');
            app.RollTotalTextArea.Value = '#';
            app.NumberofRollsPanel = uipanel(app.Panel);
            app.NumberofRollsPanel.Title = 'Number of Rolls';
            app.Number = uieditfield(app.NumberofRollsPanel, 'text');
            app.Number.Value = '#';
            app.EqualPanel = uieditfield(app.Panel, 'text');
            app.EqualPanel.Value = '=';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end